classdef DualMatrix < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        ParentPanel                   matlab.ui.container.Panel
        ConfirmButtonImage            matlab.ui.control.Image
        ButtonPanel                   matlab.ui.container.Panel
        MultiplicationButton          matlab.ui.control.StateButton
        SubtractionButton             matlab.ui.control.StateButton
        AdditionButton                matlab.ui.control.StateButton
        MatrixTab                     matlab.ui.container.TabGroup
        Tab3x3                        matlab.ui.container.Tab
        A11                           matlab.ui.control.NumericEditField
        A12                           matlab.ui.control.NumericEditField
        A13                           matlab.ui.control.NumericEditField
        A21                           matlab.ui.control.NumericEditField
        A22                           matlab.ui.control.NumericEditField
        A23                           matlab.ui.control.NumericEditField
        A31                           matlab.ui.control.NumericEditField
        A32                           matlab.ui.control.NumericEditField
        A33                           matlab.ui.control.NumericEditField
        B11                           matlab.ui.control.NumericEditField
        B12                           matlab.ui.control.NumericEditField
        B13                           matlab.ui.control.NumericEditField
        B21                           matlab.ui.control.NumericEditField
        B22                           matlab.ui.control.NumericEditField
        B23                           matlab.ui.control.NumericEditField
        B31                           matlab.ui.control.NumericEditField
        B32                           matlab.ui.control.NumericEditField
        B33                           matlab.ui.control.NumericEditField
        C11                           matlab.ui.control.NumericEditField
        C12                           matlab.ui.control.NumericEditField
        C13                           matlab.ui.control.NumericEditField
        C21                           matlab.ui.control.NumericEditField
        C22                           matlab.ui.control.NumericEditField
        C23                           matlab.ui.control.NumericEditField
        C31                           matlab.ui.control.NumericEditField
        C32                           matlab.ui.control.NumericEditField
        C33                           matlab.ui.control.NumericEditField
        SignImage                     matlab.ui.control.Image
        ArrowImage                    matlab.ui.control.Image
        Tab2x2                        matlab.ui.container.Tab
        NumberofColumnsDropDown       matlab.ui.control.DropDown
        NumberofRowsDropDown          matlab.ui.control.DropDown
        NumberofRowsDropDownLabel     matlab.ui.control.Label
        NumberofColumnsDropDownLabel  matlab.ui.control.Label
    end


    methods (Access = private)

        function [A, B] = GetMatrix(app)
            %Getting the order of our matrix
            order = str2double(app.NumberofColumnsDropDown.Value);

            %Pre-Allocating the matrices
            A = zeros(order);
            B = zeros(order);

            %Now using the for loop for the very first time :)

            %Matrix A
            for i = 1 : order
                for j = 1 : order
                    A(i, j) = app.("A"+i+j).Value;
                end
            end

            %Matrix B
            for i = 1 : order
                for j = 1 : order
                    B(i, j) = app.("B"+i+j).Value;
                end
            end


        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.MatrixTab.Visible = 'off';
            app.SignImage.Visible = 'off';
            
        end

        % Image clicked function: ConfirmButtonImage
        function ConfirmButtonImageClicked(app, event)
            app.MatrixTab.Visible = 'on';

            if app.NumberofColumnsDropDown.Value == '2' && app.NumberofRowsDropDown.Value == '2'
                app.MatrixTab.SelectedTab = app.Tab2x2;
            elseif app.NumberofColumnsDropDown.Value == '3' && app.NumberofRowsDropDown.Value == '3'
                app.MatrixTab.SelectedTab = app.Tab3x3;
            end
            
        end

        % Value changed function: AdditionButton
        function AdditionButtonValueChanged(app, event)

            %Getting the Values
            order = str2double(app.NumberofColumnsDropDown.Value);
            [A, B] = app.GetMatrix();

            %Getting the Answer
            C = A + B ;

            %Putting the Answer
            for i = 1 : order
                for j = 1 : order
                    app.("C"+i+j).Value = C(i,j);
                end
            end

            %Backend Stuff
            app.SignImage.ImageSource = 'F:\MyMatlabStuff\Matlab\AddSign.png';
            app.SignImage.Visible = 'on';

            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ParentPanel = uipanel(app.UIFigure);
            app.ParentPanel.Title = 'DualMatrix Calculator';
            app.NumberofColumnsDropDownLabel = uilabel(app.ParentPanel);
            app.NumberofColumnsDropDownLabel.Text = 'Number of Columns';
            app.NumberofRowsDropDownLabel = uilabel(app.ParentPanel);
            app.NumberofRowsDropDownLabel.Text = 'Number of Rows';
            app.NumberofRowsDropDown = uidropdown(app.ParentPanel);
            app.NumberofRowsDropDown.Items = {'2', '3'};
            app.NumberofRowsDropDown.Value = '2';
            app.NumberofColumnsDropDown = uidropdown(app.ParentPanel);
            app.NumberofColumnsDropDown.Items = {'2', '3'};
            app.NumberofColumnsDropDown.Value = '2';
            app.MatrixTab = uitabgroup(app.ParentPanel);
            app.Tab3x3 = uitab(app.MatrixTab);
            app.Tab3x3.Title = 'Tab';
            app.ArrowImage = uiimage(app.Tab3x3);
            app.ArrowImage.ImageSource = fullfile(pathToMLAPP, 'right-arrow.png');
            app.SignImage = uiimage(app.Tab3x3);
            app.C33 = uieditfield(app.Tab3x3, 'numeric');
            app.C33.Editable = 'off';
            app.C32 = uieditfield(app.Tab3x3, 'numeric');
            app.C32.Editable = 'off';
            app.C31 = uieditfield(app.Tab3x3, 'numeric');
            app.C31.Editable = 'off';
            app.C23 = uieditfield(app.Tab3x3, 'numeric');
            app.C23.Editable = 'off';
            app.C22 = uieditfield(app.Tab3x3, 'numeric');
            app.C22.Editable = 'off';
            app.C21 = uieditfield(app.Tab3x3, 'numeric');
            app.C21.Editable = 'off';
            app.C13 = uieditfield(app.Tab3x3, 'numeric');
            app.C13.Editable = 'off';
            app.C12 = uieditfield(app.Tab3x3, 'numeric');
            app.C12.Editable = 'off';
            app.C11 = uieditfield(app.Tab3x3, 'numeric');
            app.C11.Editable = 'off';
            app.B33 = uieditfield(app.Tab3x3, 'numeric');
            app.B32 = uieditfield(app.Tab3x3, 'numeric');
            app.B31 = uieditfield(app.Tab3x3, 'numeric');
            app.B23 = uieditfield(app.Tab3x3, 'numeric');
            app.B22 = uieditfield(app.Tab3x3, 'numeric');
            app.B21 = uieditfield(app.Tab3x3, 'numeric');
            app.B13 = uieditfield(app.Tab3x3, 'numeric');
            app.B12 = uieditfield(app.Tab3x3, 'numeric');
            app.B11 = uieditfield(app.Tab3x3, 'numeric');
            app.A33 = uieditfield(app.Tab3x3, 'numeric');
            app.A32 = uieditfield(app.Tab3x3, 'numeric');
            app.A31 = uieditfield(app.Tab3x3, 'numeric');
            app.A23 = uieditfield(app.Tab3x3, 'numeric');
            app.A22 = uieditfield(app.Tab3x3, 'numeric');
            app.A21 = uieditfield(app.Tab3x3, 'numeric');
            app.A13 = uieditfield(app.Tab3x3, 'numeric');
            app.A12 = uieditfield(app.Tab3x3, 'numeric');
            app.A11 = uieditfield(app.Tab3x3, 'numeric');
            app.Tab2x2 = uitab(app.MatrixTab);
            app.Tab2x2.Title = 'Tab2';
            app.ButtonPanel = uipanel(app.ParentPanel);
            app.AdditionButton = uibutton(app.ButtonPanel, 'state');
            app.AdditionButton.ValueChangedFcn = createCallbackFcn(app, @AdditionButtonValueChanged, true);
            app.AdditionButton.Text = 'Addition';
            app.SubtractionButton = uibutton(app.ButtonPanel, 'state');
            app.SubtractionButton.Text = 'Subtraction';
            app.MultiplicationButton = uibutton(app.ButtonPanel, 'state');
            app.MultiplicationButton.Text = 'Multiplication';
            app.ConfirmButtonImage = uiimage(app.ParentPanel);
            app.ConfirmButtonImage.ImageClickedFcn = createCallbackFcn(app, @ConfirmButtonImageClicked, true);
            app.ConfirmButtonImage.ImageSource = fullfile(pathToMLAPP, 'ConfirmSign.png');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DualMatrix

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end